classdef Fisica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CaidalibreTab                   matlab.ui.container.Tab
        CalcularButton                  matlab.ui.control.Button
        AlturaEditField                 matlab.ui.control.NumericEditField
        AlturaEditFieldLabel            matlab.ui.control.Label
        VelocidadInicialEditField       matlab.ui.control.NumericEditField
        VelocidadInicialEditFieldLabel  matlab.ui.control.Label
        AnguloconlahorizontalEditField  matlab.ui.control.NumericEditField
        AnguloconlahorizontalEditFieldLabel  matlab.ui.control.Label
        axe                             matlab.ui.control.UIAxes
        MovimientoCircularTab           matlab.ui.container.Tab
        y1EditField                     matlab.ui.control.NumericEditField
        y1EditFieldLabel                matlab.ui.control.Label
        velocidadtangencialEditField    matlab.ui.control.NumericEditField
        velocidadtangencialEditFieldLabel  matlab.ui.control.Label
        tiempoEditField                 matlab.ui.control.NumericEditField
        tiempoEditFieldLabel            matlab.ui.control.Label
        radioEditField                  matlab.ui.control.NumericEditField
        radioEditFieldLabel             matlab.ui.control.Label
        CalcularButton_2                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: VelocidadInicialEditField
        function Vo(app, event)
            value = app.VelocidadInicialEditField.Value;
            
        end

        % Button pushed function: CalcularButton
        function CalcularCaidaLibre(app, event)
            cla(app.axe,"reset");
            Vel_inicial = app.VelocidadInicialEditField.Value;
            teta = app.AnguloconlahorizontalEditField.Value;
            H = app.AlturaEditField.Value;
            g = 9.8;
            t = ((Vel_inicial * sin(teta)) + ((Vel_inicial * sin(teta))^2+2*H*g)^0.5)/g;
            teta = teta*pi/180;
            xmax = Vel_inicial * cos(teta) * t;
            ymax = H + ((Vel_inicial*sin(teta))^2)/(2*g);

            for i = 0:0.5:xmax
                x = 0:0.5:xmax;
                y3 = H + (tan(teta)*i)-(0.5*g/(Vel_inicial*cos(teta))^2) * (i ^ 2);
                hold on;
                pause(0.1);
                plot(app.axe,i,y3,LineStyle="-");
                axis([0 xmax 0 ymax]);

                grid(app.axe,"on");
            end


        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            t = app.tiempoEditField.Value;
            y1 = app.y1EditField.Value;
            R = app.radioEditField.Value;
            Vt = app.velocidadtangencialEditField.Value;
            w = Vt/R;
            y_1 = y1*pi/180;
            for i=0:0,03:t
                teta = y_1+w*i;
                %figure(1);
                %hold on;

                %polarscatter(teta,R);
                pause(0.2);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CaidalibreTab = uitab(app.TabGroup);
            app.CaidalibreTab.Title = 'Caida libre';
            app.axe = uiaxes(app.CaidalibreTab);
            title(app.axe, 'Title')
            xlabel(app.axe, 'X')
            ylabel(app.axe, 'Y')
            zlabel(app.axe, 'Z')
            colormap(app.axe, 'turbo')
            app.AnguloconlahorizontalEditFieldLabel = uilabel(app.CaidalibreTab);
            app.AnguloconlahorizontalEditFieldLabel.Text = 'Angulo con la horizontal';
            app.AnguloconlahorizontalEditField = uieditfield(app.CaidalibreTab, 'numeric');
            app.AnguloconlahorizontalEditField.Tag = 'anguloH';
            app.VelocidadInicialEditFieldLabel = uilabel(app.CaidalibreTab);
            app.VelocidadInicialEditFieldLabel.Text = 'Velocidad Inicial';
            app.VelocidadInicialEditField = uieditfield(app.CaidalibreTab, 'numeric');
            app.VelocidadInicialEditField.ValueChangedFcn = createCallbackFcn(app, @Vo, true);
            app.VelocidadInicialEditField.Tag = 'Vo';
            app.AlturaEditFieldLabel = uilabel(app.CaidalibreTab);
            app.AlturaEditFieldLabel.Text = 'Altura';
            app.AlturaEditField = uieditfield(app.CaidalibreTab, 'numeric');
            app.AlturaEditField.Tag = 'H';
            app.CalcularButton = uibutton(app.CaidalibreTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCaidaLibre, true);
            app.CalcularButton.Tag = 'Calcular';
            app.CalcularButton.Text = 'Calcular';
            app.MovimientoCircularTab = uitab(app.TabGroup);
            app.MovimientoCircularTab.Title = 'Movimiento Circular';
            app.CalcularButton_2 = uibutton(app.MovimientoCircularTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Tag = 'Calcular';
            app.CalcularButton_2.Text = 'Calcular';
            app.radioEditFieldLabel = uilabel(app.MovimientoCircularTab);
            app.radioEditFieldLabel.Text = 'radio';
            app.radioEditField = uieditfield(app.MovimientoCircularTab, 'numeric');
            app.radioEditField.Tag = 'anguloH';
            app.tiempoEditFieldLabel = uilabel(app.MovimientoCircularTab);
            app.tiempoEditFieldLabel.Text = 'tiempo';
            app.tiempoEditField = uieditfield(app.MovimientoCircularTab, 'numeric');
            app.tiempoEditField.Tag = 'Vo';
            app.velocidadtangencialEditFieldLabel = uilabel(app.MovimientoCircularTab);
            app.velocidadtangencialEditFieldLabel.Text = 'velocidad tangencial';
            app.velocidadtangencialEditField = uieditfield(app.MovimientoCircularTab, 'numeric');
            app.velocidadtangencialEditField.Tag = 'H';
            app.y1EditFieldLabel = uilabel(app.MovimientoCircularTab);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.MovimientoCircularTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fisica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end